classdef AnimeAndData_WithSticks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        thSlider         matlab.ui.control.Slider
        axAnime          matlab.ui.control.UIAxes
        axThData         matlab.ui.control.UIAxes
        axOtherData      matlab.ui.control.UIAxes
        otherSlider      matlab.ui.control.Slider
        PlayButton       matlab.ui.control.StateButton
        axMData          matlab.ui.control.UIAxes
        MSlider          matlab.ui.control.Slider
        tEditFieldLabel  matlab.ui.control.Label
        tEditField       matlab.ui.control.NumericEditField
        timehField       matlab.ui.control.Label
    end

    
    properties (Access = public)
        pAnimes        matlab.graphics.chart.primitive.Scatter
        pThDatas       matlab.graphics.chart.primitive.Scatter
        pMDatas        matlab.graphics.chart.primitive.Scatter
        pOtherDatas    matlab.graphics.chart.primitive.Scatter
        lines          matlab.graphics.primitive.Line
        time           double
        xArray         double
        yArray         double
        thDataArray    double
        MDataArray     double
        otherDataArray double
        pauseTime      double
        sliders        matlab.ui.control.Slider
        
        stick_Axes     matlab.ui.control.UIAxes
        stick_Axes_Width double
    end
    
    
    methods (Access = private)
        
        function setPsandLines(app,targetIndex)
            for i = 2:length(app.xArray(1, :))
                app.pAnimes(i).XData = app.xArray(targetIndex, i);
                app.pAnimes(i).YData = app.yArray(targetIndex, i);
            end
            
            for i = 2:length(app.xArray(1, :)) - 2 %2つのデータを使い、かつ重心（最後）には線を引かない
                app.lines(i-1).XData = ...
                    [app.xArray(targetIndex, i), app.xArray(targetIndex, i+1)];
                app.lines(i-1).YData =...
                    [app.yArray(targetIndex, i), app.yArray(targetIndex, i+1)];
            end
            
            for i = 1:length(app.thDataArray(1, :))
                app.pThDatas(i).XData = app.time(targetIndex);
                app.pThDatas(i).YData = app.thDataArray(targetIndex, i);
            end
            
            for i = 1:length(app.MDataArray(1, :))
                app.pMDatas(i).XData = app.time(targetIndex);
                app.pMDatas(i).YData = app.MDataArray(targetIndex, i);
            end
            
            for i = 1:length(app.otherDataArray(1, :))
                app.pOtherDatas(i).XData = app.time(targetIndex);
                app.pOtherDatas(i).YData = app.otherDataArray(targetIndex, i);
            end
            
        end
        
        function setSliders(app, value)
            for i = app.sliders
                i.Value = value;
            end
        end


    end
    
    methods (Access = public)
        function restartupFcn(app, time, xArray, yArray, thDataArray, MDataArray, otherDataArray, sliderTimeh)
            startupFcn(app, time, xArray, yArray, thDataArray, MDataArray, otherDataArray, sliderTimeh)
            setSliders(app, 1)
        end
        
        function FirstPlay(app)
            app.PlayButton.Value = true;
            startTime = floor(app.thSlider.Value);
            if startTime ~= app.thSlider.Limits(2)
                for i = [startTime:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time), length(app.time)]
                    if ~app.PlayButton.Value
                        break
                    end
                    
                    setSliders(app, i);
                    setPsandLines(app,i);
                    app.tEditField.Value = app.time(i);
                    
                    drawnow
                    pause(app.pauseTime);
                end
            else
                for i = [1:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time),...
                        length(app.time)]
                    if ~app.PlayButton.Value
                        break
                    end
                    
                    setSliders(app, i);
                    setPsandLines(app,i);
                    app.tEditField.Value = app.time(i);
                    
                    drawnow
                    pause(app.pauseTime);
                end
            end
            app.PlayButton.Value = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, time, xArray, yArray, thDataArray, MDataArray, otherDataArray, sliderTimeh)
            if length(xArray)~=length(yArray)
                throw(MException('MyComponet:SizeMissmatching', 'x座標とy座標の数が合いません'))
            end
            
            %----------------------------------------------------
            %-----------------データの入力------------------------
            %----------------------------------------------------
            app.time = time;
            app.xArray = xArray;
            app.yArray = yArray;
            app.thDataArray = thDataArray;
            app.MDataArray = MDataArray;
            app.otherDataArray = otherDataArray;
            app.sliders = [app.thSlider, app.MSlider, app.otherSlider];
            app.tEditField.Value = app.time(1);
            
            app.pauseTime = 0.05;
            
            %             app.pauseTime = (app.time(2) - app.time(1));
            %             if(app.pauseTime < 0.1)
            %                 app.pauseTime = 0.1;
            %             end
            
            app.xArray = [zeros(length(app.xArray(:, 1)), 1), app.xArray];
            app.yArray = [zeros(length(app.yArray(:, 1)), 1), app.yArray];
            
            %----------------------------------------------------
            %-----------------刻み幅の表示------------------------
            %----------------------------------------------------
            app.timehField.Text = strcat('h = ', num2str(app.time(2)-app.time(1),'%10.3e'));
            
            
            %----------------------------------------------------
            %-----------------関節の表示--------------------------
            %----------------------------------------------------
            app.pAnimes(1) = scatter(app.axAnime, 0, 0);
            hold(app.axAnime, 'on')
            for i = 2:length(app.xArray(1, :))-1
                if i == 3
                    app.pAnimes(i) = scatter(app.axAnime, app.xArray(1, i), app.yArray(1, i), 100, 'rp');
                else
                    app.pAnimes(i) = scatter(app.axAnime, app.xArray(1, i), app.yArray(1, i), 'r', 'fill');
                end
            end
            
            %----------------------------------------------------
            %-----------------身体の表示--------------------------
            %----------------------------------------------------
            for i = 2:length(app.xArray(1, :)) - 2
                if i == 3
                    app.lines(i-1) = line(app.axAnime,...
                        [app.xArray(1, i), app.xArray(1, i+1)],...
                        [app.yArray(1, i), app.yArray(1, i+1)], "linewidth", 3);
                else
                    app.lines(i-1) = line(app.axAnime,...
                        [app.xArray(1, i), app.xArray(1, i+1)],...
                        [app.yArray(1, i), app.yArray(1, i+1)]);
                end
            end
            
            
            %----------------------------------------------------
            %-----------------重心の表示--------------------------
            %----------------------------------------------------
            i = length(app.xArray(1,:));
            app.pAnimes(i) = scatter(app.axAnime, app.xArray(1, i), app.yArray(1, i), 100, 'r', 'x');
            
            hold(app.axAnime, 'off')
            
            
            %----------------------------------------------------
            %-----------------角度の表示--------------------------
            %----------------------------------------------------
            plot(app.axThData, app.time, app.thDataArray)
            app.axThData.XLim = [app.time(1), app.time(end)];
            hold(app.axThData, 'on')
            for i = 1:length(app.thDataArray(1, :))
                app.pThDatas(i) = ...
                    scatter(app.axThData, app.time(1), app.thDataArray(1, i), 100, 'r', 'x');
            end
            hold(app.axThData, 'off')
            grid(app.axThData, 'on')
%             app.axThData.XTick = [app.axThData.XTick, app.time(end)];
            
            %----------------------------------------------------
            %-----------------スライダーの設定--------------------
            %----------------------------------------------------
            %             sliderTimeh = 0.2;
            app.thSlider.Limits = [1, length(app.time)];
            app.thSlider.MajorTickLabels = {};
            app.thSlider.MajorTicks = [];
            %             app.thSlider.MajorTicks = find(mod(app.time, sliderTimeh)<(app.time(2) - app.time(1))*0.9);
            %             app.thSlider.MajorTickLabels = string(app.time(mod(app.time,sliderTimeh)<(app.time(2) - app.time(1))*0.9));
            %             app.thSlider.MajorTicks = find(mod(app.time, sliderTimeh)==0);
            %             app.thSlider.MajorTickLabels = string(app.time(mod(app.time,sliderTimeh)==0));
            
            
            app.thSlider.Position(1) = app.axThData.InnerPosition(1);
            app.thSlider.Position(3) = app.axThData.InnerPosition(3);
            
            %----------------------------------------------------
            %-----------------トルクデータの表示-------------------
            %----------------------------------------------------
            plot(app.axMData, app.time, app.MDataArray)
            app.axMData.XLim = [app.time(1), app.time(end)];
            hold(app.axMData, 'on')
            for i = 1:length(app.MDataArray(1, :))
                app.pMDatas(i) = ...
                    scatter(app.axMData, app.time(1), app.MDataArray(1, i), 100, 'r', 'x');
            end
            hold(app.axMData, 'off')
            grid(app.axMData, 'on')
            
            %----------------------------------------------------
            %-----------------スライダーの設定--------------------
            %----------------------------------------------------
            app.MSlider.Limits = app.thSlider.Limits;
            app.MSlider.MajorTicks = app.thSlider.MajorTicks;
            app.MSlider.MajorTickLabels = app.thSlider.MajorTickLabels;
            
            app.MSlider.Position(1) = app.axMData.InnerPosition(1);
            app.MSlider.Position(3) = app.axMData.InnerPosition(3);
            
            %----------------------------------------------------
            %-----------------他データの表示----------------------
            %----------------------------------------------------
            plot(app.axOtherData, app.time, app.otherDataArray)
            app.axOtherData.XLim = [app.time(1), app.time(end)];
            hold(app.axOtherData, 'on')
            for i = 1:length(app.otherDataArray(1, :))
                app.pOtherDatas(i) = ...
                    scatter(app.axOtherData, app.time(1), app.otherDataArray(1,i), 100, 'r', 'x');
            end
            hold(app.axOtherData, 'off')
            grid(app.axOtherData, 'on')
            
            %----------------------------------------------------
            %-----------------スライダーの設定--------------------
            %----------------------------------------------------
            app.otherSlider.Limits = app.thSlider.Limits;
            app.otherSlider.MajorTicks = app.thSlider.MajorTicks;
            app.otherSlider.MajorTickLabels = app.thSlider.MajorTickLabels;
            
            app.otherSlider.Position(1) = app.axOtherData.InnerPosition(1);
            app.otherSlider.Position(3) = app.axOtherData.InnerPosition(3);
            
            %----------------------------------------------------
            %--------------スティックピクチャの設定----------------
            %----------------------------------------------------
            
            app.stick_Axes_Width = 130;
            Step_Time = diff(app.time(1:2));
            
            if ~any(abs(app.axThData.XTick - app.time(1)) < Step_Time / 2)
                app.axThData.XTick = [app.time(1), app.axThData.XTick];
                app.axThData.XTickLabel{1,1} = '';
            end
            
            if ~any(abs(app.axThData.XTick - app.time(end)) < Step_Time / 2)
                app.axThData.XTick = [app.axThData.XTick, app.time(end)];
                app.axThData.XTickLabel{end,1} = '';
            end
            
            for Tick_Number = 1:size(app.axThData.XTick, 2)
                if size(app.stick_Axes,2) < Tick_Number
                    target_Axes = uiaxes(app.UIFigure,"XLim",[-1.5, 1.5],"YLim",[-1, 2.5], "XTick",[], "YTick",[]);
                else
                    target_Axes = app.stick_Axes(Tick_Number);
                end
                target_Axes.XAxis.Color = 'none';
                target_Axes.YAxis.Color = 'none';
                daspect(target_Axes, [1,1,1])
                target_Axes.Position = [app.axThData.InnerPosition(1) ...
                    + app.axThData.InnerPosition(3)*(app.axThData.XTick(Tick_Number) - app.time(1))/diff(app.axThData.XLim) ...
                    - app.stick_Axes_Width/2, ...
                    app.axThData.InnerPosition(2) + app.axThData.InnerPosition(4) + app.stick_Axes_Width/10,...
                    app.stick_Axes_Width, app.stick_Axes_Width];
                
%                 target_Index = floor(app.axThData.XTick(Tick_Number)/app.axThData.XLim(1,2) * length(app.time) + 0.5);
                target_Index = floor((app.axThData.XTick(Tick_Number) - app.time(1))/Step_Time) + 1;
                
%                 if target_Index == 0
%                     target_Index = 1;
%                 end

                scatter(target_Axes, 0, 0, 30);
                hold(target_Axes, 'on')
                for i = 2:length(app.xArray(1, :))-1
                    if i == 3
                        scatter(target_Axes, app.xArray(target_Index, i), app.yArray(target_Index, i), 100, 'rp');
                    else
                        scatter(target_Axes, app.xArray(target_Index, i), app.yArray(target_Index, i), 30, 'r', 'fill');
                    end
                end
                
                for i = 2:length(app.xArray(1, :)) - 2
                    if i == 3
                        line(target_Axes,...
                            [app.xArray(target_Index, i), app.xArray(target_Index, i+1)],...
                            [app.yArray(target_Index, i), app.yArray(target_Index, i+1)], "linewidth", 3);
                    else
                        line(target_Axes,...
                            [app.xArray(target_Index, i), app.xArray(target_Index, i+1)],...
                            [app.yArray(target_Index, i), app.yArray(target_Index, i+1)]);
                    end
                end
                
                i = length(app.xArray(1,:));
                scatter(target_Axes, app.xArray(target_Index, i), app.yArray(target_Index, i), 100, 'r', 'x');
                
                hold(target_Axes, 'off')
                
                app.stick_Axes(Tick_Number) = target_Axes;
            end
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                startTime = floor(app.thSlider.Value);
                if startTime ~= app.thSlider.Limits(2)
                    for i = [startTime:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time), length(app.time)]
                            
                        if ~app.PlayButton.Value
                            break
                        end
                        
                        setSliders(app, i);
                        setPsandLines(app,i);
                        app.tEditField.Value = app.time(i);
                        
                        drawnow
                        pause(app.pauseTime);
                    end
                else
                    for i = [1:ceil(app.pauseTime/(app.time(2)-app.time(1))):length(app.time),...
                            length(app.time)]
                        if ~app.PlayButton.Value
                            break
                        end
                        
                        setSliders(app, i);
                        setPsandLines(app,i);
                        app.tEditField.Value = app.time(i);
                        
                        drawnow
                        pause(app.pauseTime);
                    end
                end
                app.PlayButton.Value = false;
            end
        end

        % Callback function: MSlider, MSlider, otherSlider, thSlider
        function sliderValueChanging(app, event)
            changingValue = event.Value;
            setSliders(app, floor(changingValue + 0.5));
            setPsandLines(app,floor(changingValue + 0.5));
            app.tEditField.Value = app.time(floor(changingValue + 0.5));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: tEditField
        function tEditFieldValueChanged(app, event)
            value = app.tEditField.Value;
            targetIndex = floor(value/(app.time(2)-app.time(1)));
            setSliders(app, targetIndex)
            setPsandLines(app,targetIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.thSlider = uislider(app.UIFigure);
            app.thSlider.Limits = [0 5];
            app.thSlider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.axAnime = uiaxes(app.UIFigure);
            title(app.axAnime, '')
            xlabel(app.axAnime, 'x')
            ylabel(app.axAnime, 'y')
            app.axAnime.XLim = [-3 3];
            app.axAnime.YLim = [-3 3];
            app.axThData = uiaxes(app.UIFigure);
            title(app.axThData, '')
            xlabel(app.axThData, 'time')
            ylabel(app.axThData, '')
            app.axOtherData = uiaxes(app.UIFigure);
            title(app.axOtherData, '')
            xlabel(app.axOtherData, 'time')
            ylabel(app.axOtherData, '')
            app.otherSlider = uislider(app.UIFigure);
            app.otherSlider.Limits = [0 5];
            app.otherSlider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.axMData = uiaxes(app.UIFigure);
            title(app.axMData, '')
            xlabel(app.axMData, 'time')
            ylabel(app.axMData, '')
            app.MSlider = uislider(app.UIFigure);
            app.MSlider.Limits = [0 5];
            app.MSlider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.MSlider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tEditFieldValueChanged, true);
            app.timehField = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimeAndData_WithSticks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end